classdef Intercambiador_de_calor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ParametersTab                 matlab.ui.container.Tab
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        JminCLabel                    matlab.ui.control.Label
        HeatExchangerTransferRateEditField  matlab.ui.control.NumericEditField
        HeatExchangerTransferRateEditFieldLabel  matlab.ui.control.Label
        ColdFluidParametersPanel      matlab.ui.container.Panel
        CelsiusLabel_2                matlab.ui.control.Label
        m3minLabel_2                  matlab.ui.control.Label
        JKgCelsiusLabel_2             matlab.ui.control.Label
        m3Label_2                     matlab.ui.control.Label
        Kgm3Label_2                   matlab.ui.control.Label
        TemperatureColdEditField      matlab.ui.control.NumericEditField
        TemperatureEditField_2Label   matlab.ui.control.Label
        FlowColdEditField             matlab.ui.control.NumericEditField
        FlowEditField_2Label          matlab.ui.control.Label
        HeatCapacityColdEditField     matlab.ui.control.NumericEditField
        HeatCapacityEditField_2Label  matlab.ui.control.Label
        TankVolumeColdEditField       matlab.ui.control.NumericEditField
        TankVolumeEditField_2Label    matlab.ui.control.Label
        DensityColdEditField          matlab.ui.control.NumericEditField
        DensityEditField_2Label       matlab.ui.control.Label
        HotFluidParametersPanel       matlab.ui.container.Panel
        TemperatureHotEditField       matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel     matlab.ui.control.Label
        FlowHotEditField              matlab.ui.control.NumericEditField
        FlowEditFieldLabel            matlab.ui.control.Label
        CelsiusLabel                  matlab.ui.control.Label
        m3minLabel                    matlab.ui.control.Label
        JKgCelsiusLabel               matlab.ui.control.Label
        m3Label                       matlab.ui.control.Label
        Kgm3Label                     matlab.ui.control.Label
        HeatCapacityHotEditField      matlab.ui.control.NumericEditField
        HeatCapacityEditFieldLabel    matlab.ui.control.Label
        TankVolumeHotEditField        matlab.ui.control.NumericEditField
        TankVolumeLabel               matlab.ui.control.Label
        DensityHotEditField           matlab.ui.control.NumericEditField
        DensityEditFieldLabel         matlab.ui.control.Label
        InitialConditionsTab          matlab.ui.container.Tab
        CelsiusLabel_4                matlab.ui.control.Label
        CelsiusLabel_3                matlab.ui.control.Label
        InitialTemperaturaColdTanksEditField  matlab.ui.control.NumericEditField
        InitialTemperaturaColdTanksLabel  matlab.ui.control.Label
        InitialTemperaturaHotTanksEditField  matlab.ui.control.NumericEditField
        InitialTemperaturaHotTanksEditFieldLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image_5                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        SimulationTab                 matlab.ui.container.Tab
        ClearButton                   matlab.ui.control.Button
        SimulateButton                matlab.ui.control.Button
        MinutesLabel                  matlab.ui.control.Label
        StoptimeEditField             matlab.ui.control.NumericEditField
        StoptimeEditFieldLabel        matlab.ui.control.Label
        hot3CheckBox                  matlab.ui.control.CheckBox
        Hot2CheckBox                  matlab.ui.control.CheckBox
        Hot1CheckBox                  matlab.ui.control.CheckBox
        Cold3CheckBox                 matlab.ui.control.CheckBox
        Cold2CheckBox                 matlab.ui.control.CheckBox
        Cold1CheckBox                 matlab.ui.control.CheckBox
        TemperaturesLabel             matlab.ui.control.Label
        Image_6                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % Las constantes del fluido frio (Cold)

            pc = app.DensityColdEditField.Value;        % la densidad en (kg*m^−3)

            Vc = app.TankVolumeColdEditField.Value;     % el volumen de l ́ıquido en cada tanque en m^3

            Cc = app.HeatCapacityColdEditField.Value;   % la capacidad calorıfica en (J·kg^−1·°C^−1)
    
            Fc = app.FlowColdEditField.Value;           % el flujo en (m^3*min^−1)

            Tc0 = app.TemperatureColdEditField.Value;   % temperatura  en °C

            %% Las constantes del fluido caliente (Hot)

            ph = app.DensityHotEditField.Value;          % la densidad en (kg*m^−3)

            Vh = app.TankVolumeHotEditField.Value;       % el volumen de l ́ıquido en cada tanque en m^3

            Ch = app.HeatCapacityHotEditField.Value;     % la capacidad calorıfica  en  (J·kg^−1·°C^−1)

            Fh = app.FlowHotEditField.Value;                   % el flujo en (m^3*min^−1);

            Th0 = app.TemperatureHotEditField.Value;          % temperatura  en °C;
            
            % La tasa de transferencia de calor del intercambiador en (J*°C^−1* min^−1)

            k = app.HeatExchangerTransferRateEditField.Value;                     
                        
            % condiciones iniciales
            
            temp_cold = app.InitialTemperaturaColdTanksEditField.Value;
            
            temp_hot  = app.InitialTemperaturaHotTanksEditField.Value;
                        
            % tiempo de muestreo
            
            tsim = app.StoptimeEditField.Value;
            
            % cold check 
           
            Cold_1 = app.Cold1CheckBox.Value
            
            Cold_2 = app.Cold2CheckBox.Value;
            
            Cold_3 = app.Cold3CheckBox.Value;
            
            Hot_1 = app.Hot1CheckBox.Value;
            
            Hot_2 = app.Hot2CheckBox.Value;
            
            Hot_3 = app.hot3CheckBox.Value;
            
                   
            % Simulik
            
            opt         = simset('SrcWorkspace','current');
            results     = sim('intercambiador_calor', tsim, opt);
           
            % Graficas 
            
            mylegends   = {'Cold_1', 'Cold_2', 'Cold_3', 'Hot_1','Hot_2','Hot_3'};
            
            flags       = [ Cold_1; Cold_2; Cold_3; Hot_1; Hot_2; Hot_3];
            
            cla(app.UIAxes);     % Boora las variables de la grafica
            
            hold(app.UIAxes, 'on');   % inicializa la grafica 
            
            grid(app.UIAxes, 'on');
            
            if flags (1) , senal1 = results.Tc1.signals.values; plot(app.UIAxes, results.Tc1.time, senal1); end
           
            if flags (2) , senal2 = results.Tc2.signals.values; plot(app.UIAxes, results.Tc2.time, senal2); end
            
            if flags (3) , senal3 = results.Tc3.signals.values; plot(app.UIAxes, results.Tc3.time, senal3); end
                
            if flags (4) , senal4 = results.Th1.signals.values; plot(app.UIAxes, results.Th1.time, senal4); end
            
            if flags (5) , senal5 = results.Th2.signals.values; plot(app.UIAxes, results.Th2.time, senal5); end
            
            if flags (6) , senal6 = results.Th3.signals.values; plot(app.UIAxes, results.Th3.time, senal6); end
            
            legend(app.UIAxes, mylegends(flags));
       
        end

        % Value changed function: DensityHotEditField
        function DensityHotEditFieldValueChanged(app, event)
           
        end

        % Value changed function: Cold1CheckBox
        function Cold1CheckBoxValueChanged(app, event)
            value = app.Cold1CheckBox.Value;
            
        end

        % Value changed function: Cold2CheckBox
        function Cold2CheckBoxValueChanged(app, event)
            value = app.Cold2CheckBox.Value;
            
        end

        % Value changed function: Cold3CheckBox
        function Cold3CheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.HotFluidParametersPanel = uipanel(app.ParametersTab);
            app.HotFluidParametersPanel.Title = 'Hot Fluid Parameters';
            app.DensityEditFieldLabel = uilabel(app.HotFluidParametersPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityHotEditField = uieditfield(app.HotFluidParametersPanel, 'numeric');
            app.DensityHotEditField.ValueChangedFcn = createCallbackFcn(app, @DensityHotEditFieldValueChanged, true);
            app.DensityHotEditField.Value = 1000;
            app.TankVolumeLabel = uilabel(app.HotFluidParametersPanel);
            app.TankVolumeLabel.Text = {'Tank Volume'; ''};
            app.TankVolumeHotEditField = uieditfield(app.HotFluidParametersPanel, 'numeric');
            app.TankVolumeHotEditField.Value = 0.2;
            app.HeatCapacityEditFieldLabel = uilabel(app.HotFluidParametersPanel);
            app.HeatCapacityEditFieldLabel.Text = {'Heat Capacity'; ''; ''; ''};
            app.HeatCapacityHotEditField = uieditfield(app.HotFluidParametersPanel, 'numeric');
            app.HeatCapacityHotEditField.Value = 4180;
            app.Kgm3Label = uilabel(app.HotFluidParametersPanel);
            app.Kgm3Label.Text = 'Kg / m^3';
            app.m3Label = uilabel(app.HotFluidParametersPanel);
            app.m3Label.Text = 'm^3';
            app.JKgCelsiusLabel = uilabel(app.HotFluidParametersPanel);
            app.JKgCelsiusLabel.Text = 'J / (Kg Celsius)';
            app.m3minLabel = uilabel(app.HotFluidParametersPanel);
            app.m3minLabel.Text = 'm^3 / min';
            app.CelsiusLabel = uilabel(app.HotFluidParametersPanel);
            app.CelsiusLabel.Text = {'Celsius'; ''};
            app.FlowEditFieldLabel = uilabel(app.HotFluidParametersPanel);
            app.FlowEditFieldLabel.Text = 'Flow';
            app.FlowHotEditField = uieditfield(app.HotFluidParametersPanel, 'numeric');
            app.FlowHotEditField.Value = 0.11;
            app.TemperatureEditFieldLabel = uilabel(app.HotFluidParametersPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureHotEditField = uieditfield(app.HotFluidParametersPanel, 'numeric');
            app.TemperatureHotEditField.Value = 90;
            app.ColdFluidParametersPanel = uipanel(app.ParametersTab);
            app.ColdFluidParametersPanel.Title = 'Cold Fluid Parameters';
            app.DensityEditField_2Label = uilabel(app.ColdFluidParametersPanel);
            app.DensityEditField_2Label.Text = 'Density';
            app.DensityColdEditField = uieditfield(app.ColdFluidParametersPanel, 'numeric');
            app.DensityColdEditField.Value = 1000;
            app.TankVolumeEditField_2Label = uilabel(app.ColdFluidParametersPanel);
            app.TankVolumeEditField_2Label.Text = {'Tank Volume'; ''};
            app.TankVolumeColdEditField = uieditfield(app.ColdFluidParametersPanel, 'numeric');
            app.TankVolumeColdEditField.Value = 0.2;
            app.HeatCapacityEditField_2Label = uilabel(app.ColdFluidParametersPanel);
            app.HeatCapacityEditField_2Label.Text = {'Heat Capacity'; ''; ''; ''};
            app.HeatCapacityColdEditField = uieditfield(app.ColdFluidParametersPanel, 'numeric');
            app.HeatCapacityColdEditField.Value = 4180;
            app.FlowEditField_2Label = uilabel(app.ColdFluidParametersPanel);
            app.FlowEditField_2Label.Text = {'Flow'; ''};
            app.FlowColdEditField = uieditfield(app.ColdFluidParametersPanel, 'numeric');
            app.FlowColdEditField.Value = 0.05;
            app.TemperatureEditField_2Label = uilabel(app.ColdFluidParametersPanel);
            app.TemperatureEditField_2Label.Text = {'Temperature'; ''};
            app.TemperatureColdEditField = uieditfield(app.ColdFluidParametersPanel, 'numeric');
            app.TemperatureColdEditField.Value = 20;
            app.Kgm3Label_2 = uilabel(app.ColdFluidParametersPanel);
            app.Kgm3Label_2.Text = 'Kg / m^3';
            app.m3Label_2 = uilabel(app.ColdFluidParametersPanel);
            app.m3Label_2.Text = 'm^3';
            app.JKgCelsiusLabel_2 = uilabel(app.ColdFluidParametersPanel);
            app.JKgCelsiusLabel_2.Text = 'J / (Kg Celsius)';
            app.m3minLabel_2 = uilabel(app.ColdFluidParametersPanel);
            app.m3minLabel_2.Text = 'm^3 / min';
            app.CelsiusLabel_2 = uilabel(app.ColdFluidParametersPanel);
            app.CelsiusLabel_2.Text = {'Celsius'; ''};
            app.HeatExchangerTransferRateEditFieldLabel = uilabel(app.ParametersTab);
            app.HeatExchangerTransferRateEditFieldLabel.Text = {'Heat Exchanger Transfer Rate '; ''};
            app.HeatExchangerTransferRateEditField = uieditfield(app.ParametersTab, 'numeric');
            app.HeatExchangerTransferRateEditField.Value = 200000;
            app.JminCLabel = uilabel(app.ParametersTab);
            app.JminCLabel.Text = 'J / ( min C)';
            app.Image = uiimage(app.ParametersTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'Icono_Unicauca.png');
            app.Image_2 = uiimage(app.ParametersTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'img', 'Gifs-Matlab.gif');
            app.InitialConditionsTab = uitab(app.TabGroup);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.Image_3 = uiimage(app.InitialConditionsTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'img', 'Icono_Unicauca.png');
            app.Image_5 = uiimage(app.InitialConditionsTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'img', 'Gifs-Matlab.gif');
            app.Image2 = uiimage(app.InitialConditionsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'Imagen intercambiador de calor.png');
            app.InitialTemperaturaHotTanksEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.InitialTemperaturaHotTanksEditFieldLabel.Text = {'Initial Temperatura Hot Tanks'; ''};
            app.InitialTemperaturaHotTanksEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.InitialTemperaturaHotTanksEditField.Value = 90;
            app.InitialTemperaturaColdTanksLabel = uilabel(app.InitialConditionsTab);
            app.InitialTemperaturaColdTanksLabel.Text = {'Initial Temperatura Cold Tanks'; ''};
            app.InitialTemperaturaColdTanksEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.InitialTemperaturaColdTanksEditField.Value = 20;
            app.CelsiusLabel_3 = uilabel(app.InitialConditionsTab);
            app.CelsiusLabel_3.Text = {'Celsius'; ''};
            app.CelsiusLabel_4 = uilabel(app.InitialConditionsTab);
            app.CelsiusLabel_4.Text = {'Celsius'; ''};
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes = uiaxes(app.SimulationTab);
            title(app.UIAxes, {'Graph'; ''})
            xlabel(app.UIAxes, 'Time [Minutes]')
            ylabel(app.UIAxes, '[Celsius]')
            app.Image_4 = uiimage(app.SimulationTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'img', 'Icono_Unicauca.png');
            app.Image_6 = uiimage(app.SimulationTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'img', 'Gifs-Matlab.gif');
            app.TemperaturesLabel = uilabel(app.SimulationTab);
            app.TemperaturesLabel.Text = 'Temperatures';
            app.Cold1CheckBox = uicheckbox(app.SimulationTab);
            app.Cold1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Cold1CheckBoxValueChanged, true);
            app.Cold1CheckBox.Text = 'Cold 1';
            app.Cold2CheckBox = uicheckbox(app.SimulationTab);
            app.Cold2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Cold2CheckBoxValueChanged, true);
            app.Cold2CheckBox.Text = 'Cold 2';
            app.Cold3CheckBox = uicheckbox(app.SimulationTab);
            app.Cold3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Cold3CheckBoxValueChanged, true);
            app.Cold3CheckBox.Text = 'Cold 3';
            app.Hot1CheckBox = uicheckbox(app.SimulationTab);
            app.Hot1CheckBox.Text = 'Hot 1';
            app.Hot2CheckBox = uicheckbox(app.SimulationTab);
            app.Hot2CheckBox.Text = 'Hot 2';
            app.hot3CheckBox = uicheckbox(app.SimulationTab);
            app.hot3CheckBox.Text = {'hot 3'; ''};
            app.StoptimeEditFieldLabel = uilabel(app.SimulationTab);
            app.StoptimeEditFieldLabel.Text = 'Stop time';
            app.StoptimeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.StoptimeEditField.Value = 15;
            app.MinutesLabel = uilabel(app.SimulationTab);
            app.MinutesLabel.Text = {'Minutes'; ''};
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = {'Simulate'; ''};
            app.ClearButton = uibutton(app.SimulationTab, 'push');
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Intercambiador_de_calor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end